classdef pukikaki < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Knob              matlab.ui.control.DiscreteKnob
        KnobLabel         matlab.ui.control.Label
        DisconnectButton  matlab.ui.control.Button
        ConnectButton     matlab.ui.control.Button
        Lamp              matlab.ui.control.Lamp
        LampLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
         client % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.client = opcua('10.3.7.26', 4840); 
            connect(app.client);
            app.Lamp.Color = [0, 1, 0];
    
            %disp('Prepojenie uspesne.');
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            disconnect(app.client);
            app.Lamp.Color = [1, 0, 0];
            clear app.client;
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;

            node = findNodeByName(app.client.Namespace,'Mode');
            if(value=='0')
                writeValue(app.client, node, 0);
            end
            if(value=='1')
                writeValue(app.client, node, 1);
            end
            if(value=='2')
                writeValue(app.client, node, 2);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.KnobLabel = uilabel(app.UIFigure);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'discrete');
            app.Knob.Items = {'0.', '1', '2'};
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = '0.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pukikaki

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end